classdef RITE_Dos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RITEDosUIFigure                 matlab.ui.Figure
        RITEDosLabel                    matlab.ui.control.Label
        RadiotherapyInvivoTwoDimensionalEPIDDosimetryLabel  matlab.ui.control.Label
        CTSimulatorPanel                matlab.ui.container.Panel
        LoadPathEditFieldLabel          matlab.ui.control.Label
        CTSim_LoadPathEditField         matlab.ui.control.EditField
        CTSim_NewCommissionButton       matlab.ui.control.StateButton
        CTSim_EditButton                matlab.ui.control.StateButton
        CTSim_LoadButton                matlab.ui.control.StateButton
        UnitNameEditFieldLabel          matlab.ui.control.Label
        CTSim_UnitNameEditField         matlab.ui.control.EditField
        LinearAcceleratorEPIDPanel      matlab.ui.container.Panel
        LoadPathEditField_2Label        matlab.ui.control.Label
        Linac_LoadPathEditField         matlab.ui.control.EditField
        Linac_NewCommissionButton       matlab.ui.control.StateButton
        Linac_EditButton                matlab.ui.control.StateButton
        Linac_LoadButton                matlab.ui.control.StateButton
        UnitNameEditField_2Label        matlab.ui.control.Label
        Linac_UnitNameEditField         matlab.ui.control.EditField
        CommissionedEnergiesDropDownLabel  matlab.ui.control.Label
        Linac_CommissionedEnergiesDropDown  matlab.ui.control.DropDown
        Linac_CommissionedEnergiesEditButton  matlab.ui.control.StateButton
        Linac_NewCommissionEnergyButton  matlab.ui.control.StateButton
        Label                           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        CTSimulatorEditTab              matlab.ui.container.Tab
        EditCommissionCTSimulatorLabel  matlab.ui.control.Label
        UnitNameEditFieldLabel_2        matlab.ui.control.Label
        CTSim_Edit_UnitNameEditField    matlab.ui.control.EditField
        SlopeEditFieldLabel             matlab.ui.control.Label
        CTSim_Edit_NegRed_SlopeEditField  matlab.ui.control.NumericEditField
        HUREDLabel                      matlab.ui.control.Label
        RelativeElectronDensityREDCurveLabel  matlab.ui.control.Label
        HUValueCurveLabel               matlab.ui.control.Label
        HULabel                         matlab.ui.control.Label
        InterceptEditFieldLabel         matlab.ui.control.Label
        CTSim_Edit_NegRed_InterceptEditField  matlab.ui.control.NumericEditField
        HUREDLabel_4                    matlab.ui.control.Label
        HUValueCurveLabel_2             matlab.ui.control.Label
        HULabel_2                       matlab.ui.control.Label
        SlopeEditField_2Label           matlab.ui.control.Label
        CTSim_Edit_PosRed_SlopeEditField  matlab.ui.control.NumericEditField
        InterceptEditField_2Label       matlab.ui.control.Label
        CTSim_Edit_PosRed_InterceptEditField  matlab.ui.control.NumericEditField
        AirCutoffEditFieldLabel         matlab.ui.control.Label
        CTSim_Edit_AirCutoffEditField   matlab.ui.control.NumericEditField
        HULabel_3                       matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CTSim_Edit_SaveButton           matlab.ui.control.StateButton
        CTSim_Edit_CancelButton         matlab.ui.control.StateButton
        NotesLabel                      matlab.ui.control.Label
        CTSim_Edit_NotesTextArea        matlab.ui.control.TextArea
        LinacEditTab                    matlab.ui.container.Tab
        EditCommissionLinearAcceleratorEPIDLabel  matlab.ui.control.Label
        EPIDDimensionsSettingsLabel     matlab.ui.control.Label
        NumberofPixelsLabel             matlab.ui.control.Label
        cmLabel                         matlab.ui.control.Label
        PixelDimensionsLabel            matlab.ui.control.Label
        cmLabel_2                       matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Linac_Edit_SaveButton           matlab.ui.control.StateButton
        Linac_Edit_CancelButton         matlab.ui.control.StateButton
        UnitNameEditFieldLabel_3        matlab.ui.control.Label
        Linac_Edit_UnitNameEditField    matlab.ui.control.EditField
        CrossplaneLabel                 matlab.ui.control.Label
        Linac_Edit_NumPixelsCrossplaneEditField  matlab.ui.control.NumericEditField
        InplaneLabel                    matlab.ui.control.Label
        Linac_Edit_NumPixelsInplaneEditField  matlab.ui.control.NumericEditField
        CrossplaneLabel_2               matlab.ui.control.Label
        Linac_Edit_PixelDimsCrossplaneEditField  matlab.ui.control.NumericEditField
        InplaneLabel_2                  matlab.ui.control.Label
        Linac_Edit_PixelDimsInplaneEditField  matlab.ui.control.NumericEditField
        LinacParametersLabel            matlab.ui.control.Label
        cmLabel_3                       matlab.ui.control.Label
        SourcetoIsocentreDistanceLabel  matlab.ui.control.Label
        Linac_Edit_SourceToIsocentreEditField  matlab.ui.control.NumericEditField
        EPIDtoIsocentreDistanceLabel    matlab.ui.control.Label
        Linac_Edit_EpidToIsocentreEditField  matlab.ui.control.NumericEditField
        cmLabel_4                       matlab.ui.control.Label
        NotesLabel_2                    matlab.ui.control.Label
        Linac_Edit_NotesTextArea        matlab.ui.control.TextArea
        noteLinacLabel                  matlab.ui.control.Label
        noteLinacLabel_2                matlab.ui.control.Label
        Linac_Edit_EpidImagingSettingsButtonGroup  matlab.ui.container.ButtonGroup
        IntegratedButton                matlab.ui.control.RadioButton
        ContinuousButton                matlab.ui.control.RadioButton
        FramessLabel                    matlab.ui.control.Label
        FramesPerSecondEditField        matlab.ui.control.NumericEditField
        FramesImageLabel                matlab.ui.control.Label
        FramesPerImageEditField         matlab.ui.control.NumericEditField
        noteLinacLabel_3                matlab.ui.control.Label
        noteLinacLabel_4                matlab.ui.control.Label
        noteLinacLabel_5                matlab.ui.control.Label
        noteLinacLabel_6                matlab.ui.control.Label
        noteLinacLabel_7                matlab.ui.control.Label
        EnergyEditTab                   matlab.ui.container.Tab
        EditCommissionPhotonBeamLabel   matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Beam_Edit_SaveButton            matlab.ui.control.StateButton
        Beam_Edit_CancelButton          matlab.ui.control.StateButton
        NotesLabel_3                    matlab.ui.control.Label
        Beam_Edit_NotesTextArea         matlab.ui.control.TextArea
        MeVLabel                        matlab.ui.control.Label
        BeamEnergyLabel                 matlab.ui.control.Label
        Beam_Edit_BeamEnergyEditField   matlab.ui.control.NumericEditField
        CommissioningStepsLabel         matlab.ui.control.Label
        ChooseCommissioningParametersLabel  matlab.ui.control.Label
        FieldSizeslLabel                matlab.ui.control.Label
        Beam_Edit_FieldSizesEditField   matlab.ui.control.EditField
        FieldscansetasxyzorstartintervalendLabel  matlab.ui.control.Label
        nxncmLabel                      matlab.ui.control.Label
        cmLabel_5                       matlab.ui.control.Label
        thewidththicknessofthesolidwaterphantomstobeusedLabel  matlab.ui.control.Label
        PhantomWidthswLabel             matlab.ui.control.Label
        Beam_Edit_PhantomWidthsEditField  matlab.ui.control.EditField
        cmLabel_6                       matlab.ui.control.Label
        theamountthecentreofthesolidwaterphantomswillbeshiftedLabel  matlab.ui.control.Label
        PhantomShiftsdLabel             matlab.ui.control.Label
        Beam_Edit_PhantomShiftsEditField  matlab.ui.control.EditField
        fromisocentred0isashiftupwardsd0isdownwardsLabel  matlab.ui.control.Label
        ChooseandcreatedirectoriestoholdcommissioningdataLabel  matlab.ui.control.Label
        text_step2                      matlab.ui.control.Label
        test_Step2_2                    matlab.ui.control.Label
        test_step2_3                    matlab.ui.control.Label
        CollecttheEPIDdataandplaceitwithintheappropriatedirectoryLabel  matlab.ui.control.Label
        SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_4  matlab.ui.control.Label
        SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_6  matlab.ui.control.Label
        LoadTissuePhantomRatioDataLabel  matlab.ui.control.Label
        SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_7  matlab.ui.control.Label
        SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_8  matlab.ui.control.Label
        CalculatecommissioningvaluesLabel  matlab.ui.control.Label
        SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_9  matlab.ui.control.Label
        Beam_Edit_CommissioningDataPathEditField  matlab.ui.control.EditField
        Beam_Edit_CommissioningDataPathSelectButton  matlab.ui.control.StateButton
        Beam_Edit_CommissioningDataDirectoriesCreateButton  matlab.ui.control.StateButton
        Label_5                         matlab.ui.control.Label
        Beam_Edit_CommissioningCalculateAndSaveButton  matlab.ui.control.StateButton
        Beam_Edit_TprDataPathEditField  matlab.ui.control.EditField
        Beam_Edit_TprDataPathSelectButton  matlab.ui.control.StateButton
        Beam_Edit_TprDataLoadButton     matlab.ui.control.StateButton
        MULabel_2                       matlab.ui.control.Label
        DeliveredMUsLabel               matlab.ui.control.Label
        Beam_Edit_DeliveredMUsEditField  matlab.ui.control.NumericEditField
        Beam_Edit_RemoveButton          matlab.ui.control.StateButton
        MUminLabel                      matlab.ui.control.Label
        DoseRateLabel                   matlab.ui.control.Label
        Beam_Edit_DoseRateEditField     matlab.ui.control.NumericEditField
        cmLabel_7                       matlab.ui.control.Label
        theamountthecentreofthesolidwaterphantomswillbeshiftedLabel_2  matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        fCommissioningParametersLabel   matlab.ui.control.Label
        FCommissioningParameterLabel    matlab.ui.control.Label
        theamountthecentreofthesolidwaterphantomswillbeshiftedLabel_3  matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        FieldSizesforFandfCommissioningLabel  matlab.ui.control.Label
        PhantomWidthwLabel              matlab.ui.control.Label
        Beam_Edit_PhantomWidthForShiftsEditField  matlab.ui.control.NumericEditField
        SettingsTab                     matlab.ui.container.Tab
        DefaultCTSimulatorPathLabel     matlab.ui.control.Label
        Settings_CTSimPathEditField     matlab.ui.control.EditField
        Settings_CTSimPathSelectButton  matlab.ui.control.StateButton
        Settings_LinacPathSelectButton  matlab.ui.control.StateButton
        DefaultLinearAcceleratorPathLabel  matlab.ui.control.Label
        Settings_LinacPathEditField     matlab.ui.control.EditField
        Settings_EpidDicomPathSelectButton  matlab.ui.control.StateButton
        DefaultEPIDDicomPathLabel       matlab.ui.control.Label
        Settings_EpidDicomPathEditField  matlab.ui.control.EditField
        Settings_TpsDataPathSelectButton  matlab.ui.control.StateButton
        DefaultTreatmentPlanningDataPathLabel  matlab.ui.control.Label
        Settings_TpsDataPathEditField   matlab.ui.control.EditField
        Settings_CloseButton            matlab.ui.control.StateButton
        TPSDoseGridScalingDICOMFieldNameLabel  matlab.ui.control.Label
        Beam_Edit_FieldSizesEditField_2  matlab.ui.control.EditField
        AveragingWindowSideLengthLabel  matlab.ui.control.Label
        Beam_Edit_DoseRateEditField_2   matlab.ui.control.NumericEditField
        pixelsLabel                     matlab.ui.control.Label
        cmLabel_8                       matlab.ui.control.Label
        InterpolationGridSpacingforCommissioningLabel  matlab.ui.control.Label
        Beam_Edit_DoseRateEditField_3   matlab.ui.control.NumericEditField
        PatientDoseCalculationPanel     matlab.ui.container.Panel
        CTDataLoadPathLabel             matlab.ui.control.Label
        DoseCalc_CTDataPathEditField    matlab.ui.control.EditField
        DoseCalc_CTDataPathLoadButton   matlab.ui.control.StateButton
        DoseCalc_CalculateButton        matlab.ui.control.StateButton
        Label_6                         matlab.ui.control.Label
        EPIDDataLoadPathLabel           matlab.ui.control.Label
        DoseCalc_EpidPathEditField      matlab.ui.control.EditField
        DoseCalc_EpidPathLoadButton     matlab.ui.control.StateButton
        Label_10                        matlab.ui.control.Label
        GantryAngleLabel                matlab.ui.control.Label
        DoseCalc_GantryAngleEditField   matlab.ui.control.NumericEditField
        NumberofFractionsLabel          matlab.ui.control.Label
        DoseCalc_NumFractionsEditField  matlab.ui.control.NumericEditField
        TreatmentPlanningSystemPatientDoseMapLoadPathLabel  matlab.ui.control.Label
        DoseCalc_TpsPathEditField       matlab.ui.control.EditField
        DoseCalc_TpsPathLoadButton      matlab.ui.control.StateButton
        DoseCalc_PatientDoseCalculationLoadButton  matlab.ui.control.StateButton
        PathLabel                       matlab.ui.control.Label
        DoseCalc_CTDataPathEditField_2  matlab.ui.control.EditField
        LoadPatientDoseCalculationLabel  matlab.ui.control.Label
        DoseCalc_ViewDoseMapsButton     matlab.ui.control.StateButton
        DoseCalc_ExportButton           matlab.ui.control.StateButton
        Label_7                         matlab.ui.control.Label
        DrStefanoPecaemailLabel         matlab.ui.control.Label
        DavidDeVriesemailLabel          matlab.ui.control.Label
        ZachWhatmanemailLabel           matlab.ui.control.Label
        Settings_EditButton             matlab.ui.control.StateButton
        Settings_EditButton_2           matlab.ui.control.StateButton
        Settings_EditButton_3           matlab.ui.control.StateButton
    end


    properties (Access = public)
        ctSim = CTSim.empty % loaded CTSim object
        ctSimLoadPath = '' % path where the above object came from
        ctSimCreatingNew = false
        
        linacAndEpid = LinacAndEPID.empty % loaded LinacAndEpid object
        linacAndEpidLoadPath = '' % path where the above object came from
        linacAndEpidCreatingNew = false
        
        patientDoseCalculation = PatientDoseCalculation % loaded PatientDoseCalculation object
        
        settings = [] % a Settings object, loaded from the same directory as the app
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startupFcnCallback(app);
        end

        % Value changed function: Beam_Edit_CancelButton
        function Beam_Edit_CancelButtonValueChanged(app, event)
            value = app.Beam_Edit_CancelButton.Value;
            
        end

        % Value changed function: 
        % Beam_Edit_CommissioningCalculateAndSaveButton
        function Beam_Edit_CommissioningCalculateAndSaveButtonValueChanged(app, event)
            Beam_Edit_CommissioningCalculateAndSaveButtonCallback(app);
        end

        % Value changed function: 
        % Beam_Edit_CommissioningDataDirectoriesCreateButton
        function Beam_Edit_CommissioningDataDirectoriesCreateButtonValueChanged(app, event)
            Beam_Edit_CommissioningDataDirectoriesCreateButtonCallback(app);
        end

        % Value changed function: 
        % Beam_Edit_CommissioningDataPathSelectButton
        function Beam_Edit_CommissioningDataPathSelectButtonValueChanged(app, event)
            Beam_Edit_CommissioningDataPathSelectButtonCallback(app);            
        end

        % Value changed function: Beam_Edit_RemoveButton
        function Beam_Edit_RemoveButtonValueChanged(app, event)
            value = app.Beam_Edit_RemoveButton.Value;
            
        end

        % Value changed function: Beam_Edit_SaveButton
        function Beam_Edit_SaveButtonValueChanged(app, event)
            value = app.Beam_Edit_SaveButton.Value;
            
        end

        % Value changed function: Beam_Edit_TprDataLoadButton
        function Beam_Edit_TprDataLoadButtonValueChanged(app, event)
            Beam_Edit_TprDataLoadButtonCallback(app);
        end

        % Value changed function: Beam_Edit_TprDataPathSelectButton
        function Beam_Edit_TprDataPathSelectButtonValueChanged(app, event)
            Beam_Edit_TprDataPathSelectButtonCallback(app);
        end

        % Value changed function: CTSim_EditButton
        function CTSim_EditButtonValueChanged(app, event)
            CTSim_EditButtonCallback(app);
        end

        % Value changed function: CTSim_Edit_CancelButton
        function CTSim_Edit_CancelButtonValueChanged(app, event)
            CTSim_Edit_CancelButtonCallback(app);            
        end

        % Value changed function: CTSim_Edit_SaveButton
        function CTSim_Edit_SaveButtonValueChanged(app, event)
            CTSim_Edit_SaveButtonCallback(app);
        end

        % Value changed function: CTSim_LoadButton
        function CTSim_LoadButtonValueChanged(app, event)
            CTSim_LoadButtonCallback(app);
        end

        % Value changed function: CTSim_NewCommissionButton
        function CTSim_NewCommissionButtonValueChanged(app, event)
            CTSim_NewCommissionButtonCallback(app);            
        end

        % Value changed function: DoseCalc_CTDataPathLoadButton
        function DoseCalc_CTDataPathLoadButtonValueChanged(app, event)
            DoseCalc_CTDataPathLoadButtonCallback(app);
        end

        % Value changed function: DoseCalc_CalculateButton
        function DoseCalc_CalculateButtonValueChanged(app, event)
            DoseCalc_CalculateButtonCallback(app);
        end

        % Value changed function: DoseCalc_EpidPathLoadButton
        function DoseCalc_EpidPathLoadButtonValueChanged(app, event)
            DoseCalc_EpidPathLoadButtonCallback(app);
        end

        % Value changed function: DoseCalc_ExportButton
        function DoseCalc_ExportButtonValueChanged(app, event)
            DoseCalc_ExportButtonCallback(app);
        end

        % Value changed function: 
        % DoseCalc_PatientDoseCalculationLoadButton
        function DoseCalc_PatientDoseCalculationLoadButtonValueChanged(app, event)
            DoseCalc_PatientDoseCalculationLoadButtonCallback(app);
        end

        % Value changed function: DoseCalc_TpsPathLoadButton
        function DoseCalc_TpsPathLoadButtonValueChanged(app, event)
            DoseCalc_TpsPathLoadButtonCallback(app);
        end

        % Value changed function: DoseCalc_ViewDoseMapsButton
        function DoseCalc_ViewDoseMapsButtonValueChanged(app, event)
            DoseCalc_ViewDoseMapsButtonCallback(app);
        end

        % Value changed function: Linac_CommissionedEnergiesEditButton
        function Linac_CommissionedEnergiesEditButtonValueChanged(app, event)
            Linac_CommissionedEnergiesEditButtonCallback(app);            
        end

        % Value changed function: Linac_EditButton
        function Linac_EditButtonValueChanged(app, event)
            Linac_EditButtonCallback(app);            
        end

        % Value changed function: Linac_Edit_CancelButton
        function Linac_Edit_CancelButtonValueChanged(app, event)
            Linac_Edit_CancelButtonCallback(app);
        end

        % Selection changed function: 
        % Linac_Edit_EpidImagingSettingsButtonGroup
        function Linac_Edit_EpidImagingSettingsButtonGroupSelectionChanged(app, event)
            Linac_Edit_EpidImagingSettingsButtonGroupCallback(app);
        end

        % Value changed function: Linac_Edit_SaveButton
        function Linac_Edit_SaveButtonValueChanged(app, event)
            Linac_Edit_SaveButtonCallback(app);            
        end

        % Value changed function: Linac_LoadButton
        function Linac_LoadButtonValueChanged(app, event)
            Linac_LoadButtonCallback(app);            
        end

        % Value changed function: Linac_NewCommissionButton
        function Linac_NewCommissionButtonValueChanged(app, event)
            Linac_NewCommissionButtonCallback(app);            
        end

        % Value changed function: Linac_NewCommissionEnergyButton
        function Linac_NewCommissionEnergyButtonValueChanged(app, event)
            Linac_NewCommissionEnergyButtonCallback(app);
        end

        % Value changed function: Settings_CTSimPathSelectButton
        function Settings_CTSimPathSelectButtonValueChanged(app, event)
            Settings_CTSimPathSelectButtonCallback(app);
        end

        % Value changed function: Settings_CloseButton
        function Settings_CloseButtonValueChanged(app, event)
            Settings_CloseButtonCallback(app);
        end

        % Value changed function: Settings_EditButton
        function Settings_EditButtonValueChanged(app, event)
            Settings_EditButtonCallback(app);            
        end

        % Value changed function: Settings_EpidDicomPathSelectButton
        function Settings_EpidDicomPathSelectButtonValueChanged(app, event)
            Settings_EpidDicomPathSelectButtonCallback(app);
        end

        % Value changed function: Settings_LinacPathSelectButton
        function Settings_LinacPathSelectButtonValueChanged(app, event)
            Settings_LinacPathSelectButtonCallback(app);            
        end

        % Value changed function: Settings_TpsDataPathSelectButton
        function Settings_TpsDataPathSelectButtonValueChanged(app, event)
            Settings_TpsDataPathSelectButtonCallback(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RITEDosUIFigure = uifigure;
            app.RITEDosUIFigure.Name = 'RITE-Dos';
            app.RITEDosLabel = uilabel(app.RITEDosUIFigure);
            app.RITEDosLabel.Text = 'RITE-Dos';
            app.RadiotherapyInvivoTwoDimensionalEPIDDosimetryLabel = uilabel(app.RITEDosUIFigure);
            app.RadiotherapyInvivoTwoDimensionalEPIDDosimetryLabel.Text = 'Radiotherapy In vivo Two-Dimensional EPID Dosimetry';
            app.CTSimulatorPanel = uipanel(app.RITEDosUIFigure);
            app.CTSimulatorPanel.Title = 'CT Simulator';
            app.LoadPathEditFieldLabel = uilabel(app.CTSimulatorPanel);
            app.LoadPathEditFieldLabel.Text = 'Load Path:';
            app.CTSim_LoadPathEditField = uieditfield(app.CTSimulatorPanel, 'text');
            app.CTSim_LoadPathEditField.Editable = 'off';
            app.CTSim_NewCommissionButton = uibutton(app.CTSimulatorPanel, 'state');
            app.CTSim_NewCommissionButton.ValueChangedFcn = createCallbackFcn(app, @CTSim_NewCommissionButtonValueChanged, true);
            app.CTSim_NewCommissionButton.Text = 'New/Commission';
            app.CTSim_EditButton = uibutton(app.CTSimulatorPanel, 'state');
            app.CTSim_EditButton.ValueChangedFcn = createCallbackFcn(app, @CTSim_EditButtonValueChanged, true);
            app.CTSim_EditButton.Text = 'Edit';
            app.CTSim_LoadButton = uibutton(app.CTSimulatorPanel, 'state');
            app.CTSim_LoadButton.ValueChangedFcn = createCallbackFcn(app, @CTSim_LoadButtonValueChanged, true);
            app.CTSim_LoadButton.Text = 'Load...';
            app.UnitNameEditFieldLabel = uilabel(app.CTSimulatorPanel);
            app.UnitNameEditFieldLabel.Text = 'Unit Name:';
            app.CTSim_UnitNameEditField = uieditfield(app.CTSimulatorPanel, 'text');
            app.CTSim_UnitNameEditField.Editable = 'off';
            app.LinearAcceleratorEPIDPanel = uipanel(app.RITEDosUIFigure);
            app.LinearAcceleratorEPIDPanel.Title = 'Linear Accelerator & EPID';
            app.LoadPathEditField_2Label = uilabel(app.LinearAcceleratorEPIDPanel);
            app.LoadPathEditField_2Label.Text = 'Load Path:';
            app.Linac_LoadPathEditField = uieditfield(app.LinearAcceleratorEPIDPanel, 'text');
            app.Linac_LoadPathEditField.Editable = 'off';
            app.Linac_NewCommissionButton = uibutton(app.LinearAcceleratorEPIDPanel, 'state');
            app.Linac_NewCommissionButton.ValueChangedFcn = createCallbackFcn(app, @Linac_NewCommissionButtonValueChanged, true);
            app.Linac_NewCommissionButton.Text = 'New/Commission';
            app.Linac_EditButton = uibutton(app.LinearAcceleratorEPIDPanel, 'state');
            app.Linac_EditButton.ValueChangedFcn = createCallbackFcn(app, @Linac_EditButtonValueChanged, true);
            app.Linac_EditButton.Text = 'Edit';
            app.Linac_LoadButton = uibutton(app.LinearAcceleratorEPIDPanel, 'state');
            app.Linac_LoadButton.ValueChangedFcn = createCallbackFcn(app, @Linac_LoadButtonValueChanged, true);
            app.Linac_LoadButton.Text = 'Load...';
            app.UnitNameEditField_2Label = uilabel(app.LinearAcceleratorEPIDPanel);
            app.UnitNameEditField_2Label.Text = 'Unit Name:';
            app.Linac_UnitNameEditField = uieditfield(app.LinearAcceleratorEPIDPanel, 'text');
            app.Linac_UnitNameEditField.Editable = 'off';
            app.CommissionedEnergiesDropDownLabel = uilabel(app.LinearAcceleratorEPIDPanel);
            app.CommissionedEnergiesDropDownLabel.Text = 'Commissioned Energies:';
            app.Linac_CommissionedEnergiesDropDown = uidropdown(app.LinearAcceleratorEPIDPanel);
            app.Linac_CommissionedEnergiesDropDown.Items = {'15 MeV @ 400MU/min', '15 MeV'};
            app.Linac_CommissionedEnergiesDropDown.Value = '15 MeV @ 400MU/min';
            app.Linac_CommissionedEnergiesEditButton = uibutton(app.LinearAcceleratorEPIDPanel, 'state');
            app.Linac_CommissionedEnergiesEditButton.ValueChangedFcn = createCallbackFcn(app, @Linac_CommissionedEnergiesEditButtonValueChanged, true);
            app.Linac_CommissionedEnergiesEditButton.Text = 'Edit/Delete';
            app.Linac_NewCommissionEnergyButton = uibutton(app.LinearAcceleratorEPIDPanel, 'state');
            app.Linac_NewCommissionEnergyButton.ValueChangedFcn = createCallbackFcn(app, @Linac_NewCommissionEnergyButtonValueChanged, true);
            app.Linac_NewCommissionEnergyButton.Text = 'New/Commission';
            app.Label = uilabel(app.LinearAcceleratorEPIDPanel);
            app.Label.Text = '____________________________________________________________________';
            app.TabGroup = uitabgroup(app.RITEDosUIFigure);
            app.CTSimulatorEditTab = uitab(app.TabGroup);
            app.CTSimulatorEditTab.Title = 'CT Simulator Edit';
            app.EditCommissionCTSimulatorLabel = uilabel(app.CTSimulatorEditTab);
            app.EditCommissionCTSimulatorLabel.Text = 'Edit/Commission CT Simulator';
            app.UnitNameEditFieldLabel_2 = uilabel(app.CTSimulatorEditTab);
            app.UnitNameEditFieldLabel_2.Text = 'Unit Name:';
            app.CTSim_Edit_UnitNameEditField = uieditfield(app.CTSimulatorEditTab, 'text');
            app.SlopeEditFieldLabel = uilabel(app.CTSimulatorEditTab);
            app.SlopeEditFieldLabel.Text = 'Slope:';
            app.CTSim_Edit_NegRed_SlopeEditField = uieditfield(app.CTSimulatorEditTab, 'numeric');
            app.CTSim_Edit_NegRed_SlopeEditField.Limits = [0 Inf];
            app.CTSim_Edit_NegRed_SlopeEditField.ValueDisplayFormat = '%11.10g';
            app.HUREDLabel = uilabel(app.CTSimulatorEditTab);
            app.HUREDLabel.Text = 'HU/RED';
            app.RelativeElectronDensityREDCurveLabel = uilabel(app.CTSimulatorEditTab);
            app.RelativeElectronDensityREDCurveLabel.Text = 'Relative Electron Density (RED) Curve:';
            app.HUValueCurveLabel = uilabel(app.CTSimulatorEditTab);
            app.HUValueCurveLabel.Text = '-HU Value Curve:';
            app.HULabel = uilabel(app.CTSimulatorEditTab);
            app.HULabel.Text = 'HU';
            app.InterceptEditFieldLabel = uilabel(app.CTSimulatorEditTab);
            app.InterceptEditFieldLabel.Text = 'Intercept:';
            app.CTSim_Edit_NegRed_InterceptEditField = uieditfield(app.CTSimulatorEditTab, 'numeric');
            app.CTSim_Edit_NegRed_InterceptEditField.ValueDisplayFormat = '%11.10g';
            app.HUREDLabel_4 = uilabel(app.CTSimulatorEditTab);
            app.HUREDLabel_4.Text = 'HU/RED';
            app.HUValueCurveLabel_2 = uilabel(app.CTSimulatorEditTab);
            app.HUValueCurveLabel_2.Text = '+HU Value Curve:';
            app.HULabel_2 = uilabel(app.CTSimulatorEditTab);
            app.HULabel_2.Text = 'HU';
            app.SlopeEditField_2Label = uilabel(app.CTSimulatorEditTab);
            app.SlopeEditField_2Label.Text = 'Slope:';
            app.CTSim_Edit_PosRed_SlopeEditField = uieditfield(app.CTSimulatorEditTab, 'numeric');
            app.CTSim_Edit_PosRed_SlopeEditField.Limits = [0 Inf];
            app.CTSim_Edit_PosRed_SlopeEditField.ValueDisplayFormat = '%11.10g';
            app.InterceptEditField_2Label = uilabel(app.CTSimulatorEditTab);
            app.InterceptEditField_2Label.Text = 'Intercept:';
            app.CTSim_Edit_PosRed_InterceptEditField = uieditfield(app.CTSimulatorEditTab, 'numeric');
            app.CTSim_Edit_PosRed_InterceptEditField.ValueDisplayFormat = '%11.10g';
            app.AirCutoffEditFieldLabel = uilabel(app.CTSimulatorEditTab);
            app.AirCutoffEditFieldLabel.Text = 'Air Cutoff:';
            app.CTSim_Edit_AirCutoffEditField = uieditfield(app.CTSimulatorEditTab, 'numeric');
            app.CTSim_Edit_AirCutoffEditField.ValueDisplayFormat = '%11.10g';
            app.HULabel_3 = uilabel(app.CTSimulatorEditTab);
            app.HULabel_3.Text = 'HU';
            app.Label_2 = uilabel(app.CTSimulatorEditTab);
            app.Label_2.Text = '__________________________________________________________________________';
            app.CTSim_Edit_SaveButton = uibutton(app.CTSimulatorEditTab, 'state');
            app.CTSim_Edit_SaveButton.ValueChangedFcn = createCallbackFcn(app, @CTSim_Edit_SaveButtonValueChanged, true);
            app.CTSim_Edit_SaveButton.Text = 'Save/Create';
            app.CTSim_Edit_CancelButton = uibutton(app.CTSimulatorEditTab, 'state');
            app.CTSim_Edit_CancelButton.ValueChangedFcn = createCallbackFcn(app, @CTSim_Edit_CancelButtonValueChanged, true);
            app.CTSim_Edit_CancelButton.Text = 'Cancel';
            app.NotesLabel = uilabel(app.CTSimulatorEditTab);
            app.NotesLabel.Text = 'Notes:';
            app.CTSim_Edit_NotesTextArea = uitextarea(app.CTSimulatorEditTab);
            app.LinacEditTab = uitab(app.TabGroup);
            app.LinacEditTab.Title = 'Linac Edit';
            app.EditCommissionLinearAcceleratorEPIDLabel = uilabel(app.LinacEditTab);
            app.EditCommissionLinearAcceleratorEPIDLabel.Text = 'Edit/Commission Linear Accelerator & EPID';
            app.EPIDDimensionsSettingsLabel = uilabel(app.LinacEditTab);
            app.EPIDDimensionsSettingsLabel.Text = 'EPID Dimensions & Settings:';
            app.NumberofPixelsLabel = uilabel(app.LinacEditTab);
            app.NumberofPixelsLabel.Text = 'Number of Pixels:';
            app.cmLabel = uilabel(app.LinacEditTab);
            app.cmLabel.Text = 'cm';
            app.PixelDimensionsLabel = uilabel(app.LinacEditTab);
            app.PixelDimensionsLabel.Text = 'Pixel Dimensions:';
            app.cmLabel_2 = uilabel(app.LinacEditTab);
            app.cmLabel_2.Text = 'cm';
            app.Label_3 = uilabel(app.LinacEditTab);
            app.Label_3.Text = '_______________________________________________________________________________________';
            app.Linac_Edit_SaveButton = uibutton(app.LinacEditTab, 'state');
            app.Linac_Edit_SaveButton.ValueChangedFcn = createCallbackFcn(app, @Linac_Edit_SaveButtonValueChanged, true);
            app.Linac_Edit_SaveButton.Text = 'Create/Save';
            app.Linac_Edit_CancelButton = uibutton(app.LinacEditTab, 'state');
            app.Linac_Edit_CancelButton.ValueChangedFcn = createCallbackFcn(app, @Linac_Edit_CancelButtonValueChanged, true);
            app.Linac_Edit_CancelButton.Text = 'Cancel';
            app.UnitNameEditFieldLabel_3 = uilabel(app.LinacEditTab);
            app.UnitNameEditFieldLabel_3.Text = 'Unit Name:';
            app.Linac_Edit_UnitNameEditField = uieditfield(app.LinacEditTab, 'text');
            app.CrossplaneLabel = uilabel(app.LinacEditTab);
            app.CrossplaneLabel.Text = 'Crossplane:';
            app.Linac_Edit_NumPixelsCrossplaneEditField = uieditfield(app.LinacEditTab, 'numeric');
            app.Linac_Edit_NumPixelsCrossplaneEditField.Limits = [0 Inf];
            app.Linac_Edit_NumPixelsCrossplaneEditField.ValueDisplayFormat = '%11.10g';
            app.InplaneLabel = uilabel(app.LinacEditTab);
            app.InplaneLabel.Text = 'Inplane:';
            app.Linac_Edit_NumPixelsInplaneEditField = uieditfield(app.LinacEditTab, 'numeric');
            app.Linac_Edit_NumPixelsInplaneEditField.Limits = [0 Inf];
            app.Linac_Edit_NumPixelsInplaneEditField.ValueDisplayFormat = '%11.10g';
            app.CrossplaneLabel_2 = uilabel(app.LinacEditTab);
            app.CrossplaneLabel_2.Text = 'Crossplane:';
            app.Linac_Edit_PixelDimsCrossplaneEditField = uieditfield(app.LinacEditTab, 'numeric');
            app.Linac_Edit_PixelDimsCrossplaneEditField.Limits = [0 Inf];
            app.Linac_Edit_PixelDimsCrossplaneEditField.ValueDisplayFormat = '%11.10g';
            app.InplaneLabel_2 = uilabel(app.LinacEditTab);
            app.InplaneLabel_2.Text = 'Inplane:';
            app.Linac_Edit_PixelDimsInplaneEditField = uieditfield(app.LinacEditTab, 'numeric');
            app.Linac_Edit_PixelDimsInplaneEditField.Limits = [0 Inf];
            app.Linac_Edit_PixelDimsInplaneEditField.ValueDisplayFormat = '%11.10g';
            app.LinacParametersLabel = uilabel(app.LinacEditTab);
            app.LinacParametersLabel.Text = 'Linac Parameters:';
            app.cmLabel_3 = uilabel(app.LinacEditTab);
            app.cmLabel_3.Text = 'cm';
            app.SourcetoIsocentreDistanceLabel = uilabel(app.LinacEditTab);
            app.SourcetoIsocentreDistanceLabel.Text = 'Source to Isocentre Distance:';
            app.Linac_Edit_SourceToIsocentreEditField = uieditfield(app.LinacEditTab, 'numeric');
            app.Linac_Edit_SourceToIsocentreEditField.Limits = [0 Inf];
            app.Linac_Edit_SourceToIsocentreEditField.ValueDisplayFormat = '%11.10g';
            app.EPIDtoIsocentreDistanceLabel = uilabel(app.LinacEditTab);
            app.EPIDtoIsocentreDistanceLabel.Text = 'EPID to Isocentre Distance:';
            app.Linac_Edit_EpidToIsocentreEditField = uieditfield(app.LinacEditTab, 'numeric');
            app.Linac_Edit_EpidToIsocentreEditField.Limits = [0 Inf];
            app.Linac_Edit_EpidToIsocentreEditField.ValueDisplayFormat = '%11.10g';
            app.cmLabel_4 = uilabel(app.LinacEditTab);
            app.cmLabel_4.Text = 'cm';
            app.NotesLabel_2 = uilabel(app.LinacEditTab);
            app.NotesLabel_2.Text = 'Notes:';
            app.Linac_Edit_NotesTextArea = uitextarea(app.LinacEditTab);
            app.noteLinacLabel = uilabel(app.LinacEditTab);
            app.noteLinacLabel.Text = 'NOTE: To add, edit, or remove a commissioned photon energy for this linac';
            app.noteLinacLabel_2 = uilabel(app.LinacEditTab);
            app.noteLinacLabel_2.Text = 'create/save this linac, and then use the controls to the left to edit the energies.';
            app.Linac_Edit_EpidImagingSettingsButtonGroup = uibuttongroup(app.LinacEditTab);
            app.Linac_Edit_EpidImagingSettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Linac_Edit_EpidImagingSettingsButtonGroupSelectionChanged, true);
            app.Linac_Edit_EpidImagingSettingsButtonGroup.Title = 'Imaging Settings';
            app.IntegratedButton = uiradiobutton(app.Linac_Edit_EpidImagingSettingsButtonGroup);
            app.IntegratedButton.Text = 'Integrated';
            app.IntegratedButton.Value = true;
            app.ContinuousButton = uiradiobutton(app.Linac_Edit_EpidImagingSettingsButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.FramessLabel = uilabel(app.Linac_Edit_EpidImagingSettingsButtonGroup);
            app.FramessLabel.Text = 'Frames/s:';
            app.FramesPerSecondEditField = uieditfield(app.Linac_Edit_EpidImagingSettingsButtonGroup, 'numeric');
            app.FramesPerSecondEditField.Limits = [1 Inf];
            app.FramesPerSecondEditField.ValueDisplayFormat = '%11.10g';
            app.FramesPerSecondEditField.Value = 1;
            app.FramesImageLabel = uilabel(app.Linac_Edit_EpidImagingSettingsButtonGroup);
            app.FramesImageLabel.Text = 'Frames/Image:';
            app.FramesPerImageEditField = uieditfield(app.Linac_Edit_EpidImagingSettingsButtonGroup, 'numeric');
            app.FramesPerImageEditField.Limits = [1 Inf];
            app.FramesPerImageEditField.ValueDisplayFormat = '%.0f';
            app.FramesPerImageEditField.Value = 1;
            app.noteLinacLabel_3 = uilabel(app.Linac_Edit_EpidImagingSettingsButtonGroup);
            app.noteLinacLabel_3.Text = 'Integrated imaging is recommended for simplicity.';
            app.noteLinacLabel_4 = uilabel(app.Linac_Edit_EpidImagingSettingsButtonGroup);
            app.noteLinacLabel_4.Text = 'If Continuous imaging is used, it is recommended';
            app.noteLinacLabel_5 = uilabel(app.Linac_Edit_EpidImagingSettingsButtonGroup);
            app.noteLinacLabel_5.Text = 'to set averaging at 1 Frame/Image to avoid losing';
            app.noteLinacLabel_6 = uilabel(app.Linac_Edit_EpidImagingSettingsButtonGroup);
            app.noteLinacLabel_6.Text = 'any frames from the end of the series because';
            app.noteLinacLabel_7 = uilabel(app.Linac_Edit_EpidImagingSettingsButtonGroup);
            app.noteLinacLabel_7.Text = 'there wasn''t enough frames to average into an image.';
            app.EnergyEditTab = uitab(app.TabGroup);
            app.EnergyEditTab.Title = 'Energy Edit';
            app.EditCommissionPhotonBeamLabel = uilabel(app.EnergyEditTab);
            app.EditCommissionPhotonBeamLabel.Text = 'Edit/Commission Photon Beam';
            app.Label_4 = uilabel(app.EnergyEditTab);
            app.Label_4.Text = '_______________________________________________________________________________________________________________________________________________';
            app.Beam_Edit_SaveButton = uibutton(app.EnergyEditTab, 'state');
            app.Beam_Edit_SaveButton.ValueChangedFcn = createCallbackFcn(app, @Beam_Edit_SaveButtonValueChanged, true);
            app.Beam_Edit_SaveButton.Text = 'Save';
            app.Beam_Edit_CancelButton = uibutton(app.EnergyEditTab, 'state');
            app.Beam_Edit_CancelButton.ValueChangedFcn = createCallbackFcn(app, @Beam_Edit_CancelButtonValueChanged, true);
            app.Beam_Edit_CancelButton.Text = 'Cancel';
            app.NotesLabel_3 = uilabel(app.EnergyEditTab);
            app.NotesLabel_3.Text = 'Notes:';
            app.Beam_Edit_NotesTextArea = uitextarea(app.EnergyEditTab);
            app.MeVLabel = uilabel(app.EnergyEditTab);
            app.MeVLabel.Text = 'MeV';
            app.BeamEnergyLabel = uilabel(app.EnergyEditTab);
            app.BeamEnergyLabel.Text = 'Beam Energy:';
            app.Beam_Edit_BeamEnergyEditField = uieditfield(app.EnergyEditTab, 'numeric');
            app.Beam_Edit_BeamEnergyEditField.Limits = [0 Inf];
            app.Beam_Edit_BeamEnergyEditField.ValueDisplayFormat = '%11.10g';
            app.CommissioningStepsLabel = uilabel(app.EnergyEditTab);
            app.CommissioningStepsLabel.Text = 'Commissioning Steps:';
            app.ChooseCommissioningParametersLabel = uilabel(app.EnergyEditTab);
            app.ChooseCommissioningParametersLabel.Text = '1. Choose Commissioning Parameters';
            app.FieldSizeslLabel = uilabel(app.EnergyEditTab);
            app.FieldSizeslLabel.Text = 'Field Sizes, l:';
            app.Beam_Edit_FieldSizesEditField = uieditfield(app.EnergyEditTab, 'text');
            app.FieldscansetasxyzorstartintervalendLabel = uilabel(app.EnergyEditTab);
            app.FieldscansetasxyzorstartintervalendLabel.Text = '(Fields can set as: "x, y, z" or "start:interval:end")';
            app.nxncmLabel = uilabel(app.EnergyEditTab);
            app.nxncmLabel.Text = 'n x n (cm)';
            app.cmLabel_5 = uilabel(app.EnergyEditTab);
            app.cmLabel_5.Text = 'cm';
            app.thewidththicknessofthesolidwaterphantomstobeusedLabel = uilabel(app.EnergyEditTab);
            app.thewidththicknessofthesolidwaterphantomstobeusedLabel.Text = 'the width/thickness of the solid water phantoms to be used';
            app.PhantomWidthswLabel = uilabel(app.EnergyEditTab);
            app.PhantomWidthswLabel.Text = 'Phantom Widths, w:';
            app.Beam_Edit_PhantomWidthsEditField = uieditfield(app.EnergyEditTab, 'text');
            app.cmLabel_6 = uilabel(app.EnergyEditTab);
            app.cmLabel_6.Text = 'cm';
            app.theamountthecentreofthesolidwaterphantomswillbeshiftedLabel = uilabel(app.EnergyEditTab);
            app.theamountthecentreofthesolidwaterphantomswillbeshiftedLabel.Text = 'the amount the centre of the solid water phantoms will be shifted';
            app.PhantomShiftsdLabel = uilabel(app.EnergyEditTab);
            app.PhantomShiftsdLabel.Text = 'Phantom Shifts, d:';
            app.Beam_Edit_PhantomShiftsEditField = uieditfield(app.EnergyEditTab, 'text');
            app.fromisocentred0isashiftupwardsd0isdownwardsLabel = uilabel(app.EnergyEditTab);
            app.fromisocentred0isashiftupwardsd0isdownwardsLabel.Text = 'from isocentre (d > 0 is a shift upwards, d < 0 is downwards) ';
            app.ChooseandcreatedirectoriestoholdcommissioningdataLabel = uilabel(app.EnergyEditTab);
            app.ChooseandcreatedirectoriestoholdcommissioningdataLabel.Text = '2. Choose and create directories to hold commissioning data';
            app.text_step2 = uilabel(app.EnergyEditTab);
            app.text_step2.Text = 'Select where you want to store the commissioning EPID and treatment planning system data, and then press "Create Directories".';
            app.test_Step2_2 = uilabel(app.EnergyEditTab);
            app.test_Step2_2.Text = 'The folders will be named with the naming convention "[lXX][dYY][wZZ]", where XX is the field size, YY is the phantom shift, ';
            app.test_step2_3 = uilabel(app.EnergyEditTab);
            app.test_step2_3.Text = 'and ZZ is the phantom width. "Save" the photon beam if exiting RITE-Dos before continuing.';
            app.CollecttheEPIDdataandplaceitwithintheappropriatedirectoryLabel = uilabel(app.EnergyEditTab);
            app.CollecttheEPIDdataandplaceitwithintheappropriatedirectoryLabel.Text = '3. Collect the EPID data and place it within the appropriate directory (RITE-Dos may be closed, edit energy to continue)';
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_4 = uilabel(app.EnergyEditTab);
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_4.Text = 'Deliver the set amount of MUs (see above) to each phantom configuration (field size, width, and shift), acquire the data, and place';
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_6 = uilabel(app.EnergyEditTab);
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_6.Text = 'the Dicoms in the matching directory.';
            app.LoadTissuePhantomRatioDataLabel = uilabel(app.EnergyEditTab);
            app.LoadTissuePhantomRatioDataLabel.Text = '4. Load Tissue-Phantom Ratio Data';
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_7 = uilabel(app.EnergyEditTab);
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_7.Text = 'Data should be in a CSV file, with column headers consisting of a single number representing one length of the field size.';
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_8 = uilabel(app.EnergyEditTab);
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_8.Text = 'Each row''s first column should have a single number representing the depth in cm.';
            app.CalculatecommissioningvaluesLabel = uilabel(app.EnergyEditTab);
            app.CalculatecommissioningvaluesLabel.Text = '5. Calculate commissioning values';
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_9 = uilabel(app.EnergyEditTab);
            app.SelectwhereyouwanttostorethecommissioningEPIDdataandthenLabel_9.Text = 'Press "Calculate & Save" and wait for calculation to finish. EPID data Dicoms may be deleted after this commissioning.';
            app.Beam_Edit_CommissioningDataPathEditField = uieditfield(app.EnergyEditTab, 'text');
            app.Beam_Edit_CommissioningDataPathEditField.Editable = 'off';
            app.Beam_Edit_CommissioningDataPathSelectButton = uibutton(app.EnergyEditTab, 'state');
            app.Beam_Edit_CommissioningDataPathSelectButton.ValueChangedFcn = createCallbackFcn(app, @Beam_Edit_CommissioningDataPathSelectButtonValueChanged, true);
            app.Beam_Edit_CommissioningDataPathSelectButton.Text = 'Select Path...';
            app.Beam_Edit_CommissioningDataDirectoriesCreateButton = uibutton(app.EnergyEditTab, 'state');
            app.Beam_Edit_CommissioningDataDirectoriesCreateButton.ValueChangedFcn = createCallbackFcn(app, @Beam_Edit_CommissioningDataDirectoriesCreateButtonValueChanged, true);
            app.Beam_Edit_CommissioningDataDirectoriesCreateButton.Text = 'Create Directories';
            app.Label_5 = uilabel(app.EnergyEditTab);
            app.Label_5.Text = '_______________________________________________________________________________________________________________________________________________';
            app.Beam_Edit_CommissioningCalculateAndSaveButton = uibutton(app.EnergyEditTab, 'state');
            app.Beam_Edit_CommissioningCalculateAndSaveButton.ValueChangedFcn = createCallbackFcn(app, @Beam_Edit_CommissioningCalculateAndSaveButtonValueChanged, true);
            app.Beam_Edit_CommissioningCalculateAndSaveButton.Text = 'Calculate & Save';
            app.Beam_Edit_TprDataPathEditField = uieditfield(app.EnergyEditTab, 'text');
            app.Beam_Edit_TprDataPathEditField.Editable = 'off';
            app.Beam_Edit_TprDataPathSelectButton = uibutton(app.EnergyEditTab, 'state');
            app.Beam_Edit_TprDataPathSelectButton.ValueChangedFcn = createCallbackFcn(app, @Beam_Edit_TprDataPathSelectButtonValueChanged, true);
            app.Beam_Edit_TprDataPathSelectButton.Text = 'Select File...';
            app.Beam_Edit_TprDataLoadButton = uibutton(app.EnergyEditTab, 'state');
            app.Beam_Edit_TprDataLoadButton.ValueChangedFcn = createCallbackFcn(app, @Beam_Edit_TprDataLoadButtonValueChanged, true);
            app.Beam_Edit_TprDataLoadButton.Text = 'Load';
            app.MULabel_2 = uilabel(app.EnergyEditTab);
            app.MULabel_2.Text = 'MU';
            app.DeliveredMUsLabel = uilabel(app.EnergyEditTab);
            app.DeliveredMUsLabel.Text = 'Delivered MUs:';
            app.Beam_Edit_DeliveredMUsEditField = uieditfield(app.EnergyEditTab, 'numeric');
            app.Beam_Edit_DeliveredMUsEditField.Limits = [0 Inf];
            app.Beam_Edit_DeliveredMUsEditField.ValueDisplayFormat = '%11.10g';
            app.Beam_Edit_RemoveButton = uibutton(app.EnergyEditTab, 'state');
            app.Beam_Edit_RemoveButton.ValueChangedFcn = createCallbackFcn(app, @Beam_Edit_RemoveButtonValueChanged, true);
            app.Beam_Edit_RemoveButton.Text = 'Delete';
            app.MUminLabel = uilabel(app.EnergyEditTab);
            app.MUminLabel.Text = 'MU/min';
            app.DoseRateLabel = uilabel(app.EnergyEditTab);
            app.DoseRateLabel.Text = 'Dose Rate:';
            app.Beam_Edit_DoseRateEditField = uieditfield(app.EnergyEditTab, 'numeric');
            app.Beam_Edit_DoseRateEditField.Limits = [0 Inf];
            app.Beam_Edit_DoseRateEditField.ValueDisplayFormat = '%11.10g';
            app.cmLabel_7 = uilabel(app.EnergyEditTab);
            app.cmLabel_7.Text = 'cm';
            app.theamountthecentreofthesolidwaterphantomswillbeshiftedLabel_2 = uilabel(app.EnergyEditTab);
            app.theamountthecentreofthesolidwaterphantomswillbeshiftedLabel_2.Text = 'when taking measurements with different shifts, this single phantom width is used';
            app.Label_8 = uilabel(app.EnergyEditTab);
            app.Label_8.Text = '__________________________________________________________________________________________________________________________';
            app.fCommissioningParametersLabel = uilabel(app.EnergyEditTab);
            app.fCommissioningParametersLabel.Text = 'f Commissioning Parameters:';
            app.FCommissioningParameterLabel = uilabel(app.EnergyEditTab);
            app.FCommissioningParameterLabel.Text = 'F Commissioning Parameter:';
            app.theamountthecentreofthesolidwaterphantomswillbeshiftedLabel_3 = uilabel(app.EnergyEditTab);
            app.theamountthecentreofthesolidwaterphantomswillbeshiftedLabel_3.Text = 'recommended to use a usual patient width (15-20cm)';
            app.Label_9 = uilabel(app.EnergyEditTab);
            app.Label_9.Text = '__________________________________________________________________________________________________________________________';
            app.FieldSizesforFandfCommissioningLabel = uilabel(app.EnergyEditTab);
            app.FieldSizesforFandfCommissioningLabel.Text = 'Field Sizes for F and f Commissioning:';
            app.PhantomWidthwLabel = uilabel(app.EnergyEditTab);
            app.PhantomWidthwLabel.Text = 'Phantom Width, w:';
            app.Beam_Edit_PhantomWidthForShiftsEditField = uieditfield(app.EnergyEditTab, 'numeric');
            app.Beam_Edit_PhantomWidthForShiftsEditField.Limits = [0 Inf];
            app.Beam_Edit_PhantomWidthForShiftsEditField.ValueDisplayFormat = '%11.10g';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.DefaultCTSimulatorPathLabel = uilabel(app.SettingsTab);
            app.DefaultCTSimulatorPathLabel.Text = 'Default CT Simulator Path:';
            app.Settings_CTSimPathEditField = uieditfield(app.SettingsTab, 'text');
            app.Settings_CTSimPathEditField.Editable = 'off';
            app.Settings_CTSimPathSelectButton = uibutton(app.SettingsTab, 'state');
            app.Settings_CTSimPathSelectButton.ValueChangedFcn = createCallbackFcn(app, @Settings_CTSimPathSelectButtonValueChanged, true);
            app.Settings_CTSimPathSelectButton.Text = 'Select...';
            app.Settings_LinacPathSelectButton = uibutton(app.SettingsTab, 'state');
            app.Settings_LinacPathSelectButton.ValueChangedFcn = createCallbackFcn(app, @Settings_LinacPathSelectButtonValueChanged, true);
            app.Settings_LinacPathSelectButton.Text = 'Select...';
            app.DefaultLinearAcceleratorPathLabel = uilabel(app.SettingsTab);
            app.DefaultLinearAcceleratorPathLabel.Text = 'Default Linear Accelerator Path:';
            app.Settings_LinacPathEditField = uieditfield(app.SettingsTab, 'text');
            app.Settings_LinacPathEditField.Editable = 'off';
            app.Settings_EpidDicomPathSelectButton = uibutton(app.SettingsTab, 'state');
            app.Settings_EpidDicomPathSelectButton.ValueChangedFcn = createCallbackFcn(app, @Settings_EpidDicomPathSelectButtonValueChanged, true);
            app.Settings_EpidDicomPathSelectButton.Text = 'Select...';
            app.DefaultEPIDDicomPathLabel = uilabel(app.SettingsTab);
            app.DefaultEPIDDicomPathLabel.Text = 'Default EPID Dicom Path:';
            app.Settings_EpidDicomPathEditField = uieditfield(app.SettingsTab, 'text');
            app.Settings_EpidDicomPathEditField.Editable = 'off';
            app.Settings_TpsDataPathSelectButton = uibutton(app.SettingsTab, 'state');
            app.Settings_TpsDataPathSelectButton.ValueChangedFcn = createCallbackFcn(app, @Settings_TpsDataPathSelectButtonValueChanged, true);
            app.Settings_TpsDataPathSelectButton.Text = 'Select...';
            app.DefaultTreatmentPlanningDataPathLabel = uilabel(app.SettingsTab);
            app.DefaultTreatmentPlanningDataPathLabel.Text = 'Default Treatment Planning Data Path:';
            app.Settings_TpsDataPathEditField = uieditfield(app.SettingsTab, 'text');
            app.Settings_TpsDataPathEditField.Editable = 'off';
            app.Settings_CloseButton = uibutton(app.SettingsTab, 'state');
            app.Settings_CloseButton.ValueChangedFcn = createCallbackFcn(app, @Settings_CloseButtonValueChanged, true);
            app.Settings_CloseButton.Text = 'Close';
            app.TPSDoseGridScalingDICOMFieldNameLabel = uilabel(app.SettingsTab);
            app.TPSDoseGridScalingDICOMFieldNameLabel.Text = 'TPS Dose Grid Scaling DICOM Field Name:';
            app.Beam_Edit_FieldSizesEditField_2 = uieditfield(app.SettingsTab, 'text');
            app.Beam_Edit_FieldSizesEditField_2.Value = 'DoseGridScaling';
            app.AveragingWindowSideLengthLabel = uilabel(app.SettingsTab);
            app.AveragingWindowSideLengthLabel.Text = 'Averaging Window Side Length:';
            app.Beam_Edit_DoseRateEditField_2 = uieditfield(app.SettingsTab, 'numeric');
            app.Beam_Edit_DoseRateEditField_2.Limits = [1 Inf];
            app.Beam_Edit_DoseRateEditField_2.ValueDisplayFormat = '%.0f';
            app.Beam_Edit_DoseRateEditField_2.Value = 1;
            app.pixelsLabel = uilabel(app.SettingsTab);
            app.pixelsLabel.Text = 'pixels';
            app.cmLabel_8 = uilabel(app.SettingsTab);
            app.cmLabel_8.Text = 'cm';
            app.InterpolationGridSpacingforCommissioningLabel = uilabel(app.SettingsTab);
            app.InterpolationGridSpacingforCommissioningLabel.Text = 'Interpolation Grid Spacing for Commissioning:';
            app.Beam_Edit_DoseRateEditField_3 = uieditfield(app.SettingsTab, 'numeric');
            app.Beam_Edit_DoseRateEditField_3.Limits = [0 Inf];
            app.Beam_Edit_DoseRateEditField_3.ValueDisplayFormat = '%11.10g';
            app.Beam_Edit_DoseRateEditField_3.Value = 0.1;
            app.PatientDoseCalculationPanel = uipanel(app.RITEDosUIFigure);
            app.PatientDoseCalculationPanel.Title = 'Patient Dose Calculation';
            app.CTDataLoadPathLabel = uilabel(app.PatientDoseCalculationPanel);
            app.CTDataLoadPathLabel.Text = 'CT Data Load Path:';
            app.DoseCalc_CTDataPathEditField = uieditfield(app.PatientDoseCalculationPanel, 'text');
            app.DoseCalc_CTDataPathEditField.Editable = 'off';
            app.DoseCalc_CTDataPathLoadButton = uibutton(app.PatientDoseCalculationPanel, 'state');
            app.DoseCalc_CTDataPathLoadButton.ValueChangedFcn = createCallbackFcn(app, @DoseCalc_CTDataPathLoadButtonValueChanged, true);
            app.DoseCalc_CTDataPathLoadButton.Text = 'Load...';
            app.DoseCalc_CalculateButton = uibutton(app.PatientDoseCalculationPanel, 'state');
            app.DoseCalc_CalculateButton.ValueChangedFcn = createCallbackFcn(app, @DoseCalc_CalculateButtonValueChanged, true);
            app.DoseCalc_CalculateButton.Text = 'Calculate Dose';
            app.Label_6 = uilabel(app.PatientDoseCalculationPanel);
            app.Label_6.Text = '______________________________________________________________________';
            app.EPIDDataLoadPathLabel = uilabel(app.PatientDoseCalculationPanel);
            app.EPIDDataLoadPathLabel.Text = 'EPID Data Load Path:';
            app.DoseCalc_EpidPathEditField = uieditfield(app.PatientDoseCalculationPanel, 'text');
            app.DoseCalc_EpidPathEditField.Editable = 'off';
            app.DoseCalc_EpidPathLoadButton = uibutton(app.PatientDoseCalculationPanel, 'state');
            app.DoseCalc_EpidPathLoadButton.ValueChangedFcn = createCallbackFcn(app, @DoseCalc_EpidPathLoadButtonValueChanged, true);
            app.DoseCalc_EpidPathLoadButton.Text = 'Load...';
            app.Label_10 = uilabel(app.PatientDoseCalculationPanel);
            app.Label_10.Text = '°';
            app.GantryAngleLabel = uilabel(app.PatientDoseCalculationPanel);
            app.GantryAngleLabel.Text = 'Gantry Angle:';
            app.DoseCalc_GantryAngleEditField = uieditfield(app.PatientDoseCalculationPanel, 'numeric');
            app.DoseCalc_GantryAngleEditField.Limits = [0 Inf];
            app.DoseCalc_GantryAngleEditField.ValueDisplayFormat = '%11.10g';
            app.NumberofFractionsLabel = uilabel(app.PatientDoseCalculationPanel);
            app.NumberofFractionsLabel.Text = 'Number of Fractions:';
            app.DoseCalc_NumFractionsEditField = uieditfield(app.PatientDoseCalculationPanel, 'numeric');
            app.DoseCalc_NumFractionsEditField.Limits = [0 Inf];
            app.DoseCalc_NumFractionsEditField.ValueDisplayFormat = '%.0f';
            app.TreatmentPlanningSystemPatientDoseMapLoadPathLabel = uilabel(app.PatientDoseCalculationPanel);
            app.TreatmentPlanningSystemPatientDoseMapLoadPathLabel.Text = 'Treatment Planning System Patient Dose Map Load Path:';
            app.DoseCalc_TpsPathEditField = uieditfield(app.PatientDoseCalculationPanel, 'text');
            app.DoseCalc_TpsPathEditField.Editable = 'off';
            app.DoseCalc_TpsPathLoadButton = uibutton(app.PatientDoseCalculationPanel, 'state');
            app.DoseCalc_TpsPathLoadButton.ValueChangedFcn = createCallbackFcn(app, @DoseCalc_TpsPathLoadButtonValueChanged, true);
            app.DoseCalc_TpsPathLoadButton.Text = 'Load...';
            app.DoseCalc_PatientDoseCalculationLoadButton = uibutton(app.PatientDoseCalculationPanel, 'state');
            app.DoseCalc_PatientDoseCalculationLoadButton.ValueChangedFcn = createCallbackFcn(app, @DoseCalc_PatientDoseCalculationLoadButtonValueChanged, true);
            app.DoseCalc_PatientDoseCalculationLoadButton.Text = 'Load...';
            app.PathLabel = uilabel(app.PatientDoseCalculationPanel);
            app.PathLabel.Text = 'Path:';
            app.DoseCalc_CTDataPathEditField_2 = uieditfield(app.PatientDoseCalculationPanel, 'text');
            app.DoseCalc_CTDataPathEditField_2.Editable = 'off';
            app.LoadPatientDoseCalculationLabel = uilabel(app.PatientDoseCalculationPanel);
            app.LoadPatientDoseCalculationLabel.Text = 'Load Patient Dose Calculation';
            app.DoseCalc_ViewDoseMapsButton = uibutton(app.PatientDoseCalculationPanel, 'state');
            app.DoseCalc_ViewDoseMapsButton.ValueChangedFcn = createCallbackFcn(app, @DoseCalc_ViewDoseMapsButtonValueChanged, true);
            app.DoseCalc_ViewDoseMapsButton.Text = 'View Dose Maps';
            app.DoseCalc_ExportButton = uibutton(app.PatientDoseCalculationPanel, 'state');
            app.DoseCalc_ExportButton.ValueChangedFcn = createCallbackFcn(app, @DoseCalc_ExportButtonValueChanged, true);
            app.DoseCalc_ExportButton.Text = 'Export to .csv';
            app.Label_7 = uilabel(app.RITEDosUIFigure);
            app.Label_7.Text = '__________________________________________________________________________________________________________________________________________________________________________________________________________________________';
            app.DrStefanoPecaemailLabel = uilabel(app.RITEDosUIFigure);
            app.DrStefanoPecaemailLabel.Text = 'Dr. Stefano Peca (email)';
            app.DavidDeVriesemailLabel = uilabel(app.RITEDosUIFigure);
            app.DavidDeVriesemailLabel.Text = 'David DeVries (email)';
            app.ZachWhatmanemailLabel = uilabel(app.RITEDosUIFigure);
            app.ZachWhatmanemailLabel.Text = 'Zach Whatman (email)';
            app.Settings_EditButton = uibutton(app.RITEDosUIFigure, 'state');
            app.Settings_EditButton.ValueChangedFcn = createCallbackFcn(app, @Settings_EditButtonValueChanged, true);
            app.Settings_EditButton.Text = 'Settings...';
            app.Settings_EditButton_2 = uibutton(app.RITEDosUIFigure, 'state');
            app.Settings_EditButton_2.Enable = 'off';
            app.Settings_EditButton_2.Text = 'More Information';
            app.Settings_EditButton_3 = uibutton(app.RITEDosUIFigure, 'state');
            app.Settings_EditButton_3.Enable = 'off';
            app.Settings_EditButton_3.Text = 'Authors';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RITE_Dos

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RITEDosUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RITEDosUIFigure)
        end
    end
end